classdef mapperV3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ExportarRoadButton             matlab.ui.control.Button
        CargarArchivoCarreterasButton  matlab.ui.control.Button
        MapperLabel                    matlab.ui.control.Label
        VerCarreterasCheckBox          matlab.ui.control.CheckBox
        VerTerrenoCheckBox             matlab.ui.control.CheckBox
        CargarArchivoElevacionButton   matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Variables para guardar los datos cargados en memoria
        ElevacionZ      %Matriz de alturas
        ElevacionRef    %Referencia geografica del TIF
        %TablaCarreteras %Tabla con los datos del Shapefile
        DatosCarreteras %Estructura simple (struct)
    end
    
    methods (Access = private)
        
        function updatePlot(app)
            %updatePlot = func(app)
            %1. Limpiar ejes pero mantener configuraciones
            cla(app.UIAxes);
            hold(app.UIAxes, "on"); %Superponer capas

            %2. Dibujar terreno (Si existe data y el checkbox esta marcado)
            %Asumo que 'CheckBox' es el del terreno
            if ~isempty(app.ElevacionZ) && app.VerTerrenoCheckBox.Value == true
                %mapshow(app.UIAxes, app.ElevacionZ, app.ElevacionRef, 'DisplayType','surface');

                geoshow(app.UIAxes, app.ElevacionZ, app.ElevacionRef, 'DisplayType','surface');
                %Re-aplicar colores
                colormap(app.UIAxes,parula);
                %Solo se agrega la barra de color si no existe, para no
                %duplicar
                if isempty(app.UIAxes.Colorbar)
                    colorbar(app.UIAxes);
                end
            end

            %3. Dibujar carreteras (Si existe data y el checkbox2 esta marcado)
     
            if ~isempty(app.DatosCarreteras) && app.VerCarreterasCheckBox.Value == true
                %preparamos vectores vacios 
                lon = [app.DatosCarreteras.X];
                lat = [app.DatosCarreteras.Y];
                
                plot(app.UIAxes, lon, lat, 'Color', 'r', 'LineWidth',1.5);
               
            end

            hold(app.UIAxes, 'off');

            %Ajustes finales visuales
            axis(app.UIAxes, 'image'); %Esto corrige la distorcion (evita que el mapa se vea estirado)
            title(app.UIAxes, 'Visualización: Elevacion + Carreteras ');
            xlabel(app.UIAxes, 'Longitud');
            ylabel(app.UIAxes, 'Latitud');
        end
        
        function [x, y] = gradosAMetros(~, lat, lon, lat0, lon0)

            R_earth = 6378137; % Radio de la tierra en metros
            d2r = pi/180;

            %Formula simple
            x = (lon - lon0) * d2r * R_earth .* cos(lat0 * d2r);
            y = (lat - lat0) * d2r * R_earth;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarArchivoElevacionButton
        function ButtonCargarMapa(app, event)
            % 1. Abrir explorador de archivos para elegir el TIF
            [file, path] = uigetfile({'*.tif;*.gtif', 'Archivos GeoTIFF (*.tif, *.gtif)'});
            
            % Si el usuario cancela, no hacemos nada
            if isequal(file, 0), return; end
            
            fullpath = fullfile(path, file);

            % Cambiamos el puntero a "reloj de arena" porque cargar mapas grandes tarda un poco
            %f = uifigure; % (Solo referencia, en tu App usa la figura principal 'app.UIFigure')
            set(app.UIFigure, 'Pointer', 'watch'); drawnow;

            try
                % 2. Leer el archivo GeoTIFF
                % 'A' es la matriz de alturas (Z)
                % 'R' es el objeto de referencia geográfica (contiene latitud/longitud)
                [A, R] = readgeoraster(fullpath);
        
                % 3. Limpieza básica de datos (importante)
                % A veces los GeoTIFF tienen valores muy negativos para "sin datos" (ej. -32768)
                % Esto los convierte a NaN para que no arruinen la gráfica
                A(A < -10000) = NaN; 
        
                app.ElevacionZ = A;     %Guardamos variables
                app.ElevacionRef = R;
                
                %Llamamos a la funcion de dibujo
                updatePlot(app);

                % Éxito: Mostrar mensaje o cambiar luz de estado
                uialert(app.UIFigure, 'Elevacion cargada.', 'Éxito');
        
            catch ME
                % Si algo falla (archivo corrupto, formato incorrecto)
                uialert(app.UIFigure, ['Error al cargar: ' ME.message], 'Error');
            end
    
            % Restaurar puntero
            set(app.UIFigure, 'Pointer', 'arrow');
        end

        % Button pushed function: CargarArchivoCarreterasButton
        function ButtonCargarCarreteras(app, event)
            %Buscamos archivo .shp
            [file, path] = uigetfile({'*.shp','Esri Shapefile (*.shp)'});
            if isequal(file, 0), return; end

            fullpath = fullfile(path, file);
            set(app.UIFigure, 'Pointer', 'watch'); drawnow;

            try 
                %Leemos el Shapefile (Requiere que .shx y .dbf esten en la misma carpeta)
                S = shaperead(fullpath);
                %T = readgeotable(fullpath);

                app.DatosCarreteras = S;%T; % Guardamos variable

                %Actualizamos grafica
                updatePlot(app);

                uialert(app.UIFigure, 'Carreteras cargadas','Exito');
            catch ME
                uialert(app.UIFigure, ['Error (Asegurate de tener .shx y .dbf): ' ME.message], 'Error');
            end
            set(app.UIFigure, 'Pointer', 'arrow');
        end

        % Value changed function: VerTerrenoCheckBox
        function VerTerrenoCheckBoxValueChanged(app, event)
            updatePlot(app);
        end

        % Value changed function: VerCarreterasCheckBox
        function VerCarreterasCheckBoxValueChanged(app, event)
            updatePlot(app);
        end

        % Button pushed function: ExportarRoadButton
        function ButtonExportarRoadRunner(app, event)
            if isempty(app.DatosCarreteras) || isempty(app.ElevacionZ)
                uialert(app.UIFigure, 'Carga los datos de elevacion y carreteras primero', 'Faltan datos');
                return;
            end

            %cambiar cursor a espera
            set(app.UIFigure, 'Pointer', 'watch'); drawnow;

            try
                %1. Inicializar el MAPA HD
                rrMap = roadrunnerHDMap;

                %2. Definir el origen local (El centro del mapa) en mtr
                
                %lat0 = app.ElevacionRef.LatitudeLimits(1) + diff(app.ElevacionRef.LatitudeLimits)/2;
                %lon0 = app.ElevacionRef.LongitudeLimits(1) + diff(app.ElevacionRef.LongitudeLimits)/2;
                lat0 = mean(app.ElevacionRef.LatitudeLimits);
                lon0 = mean(app.ElevacionRef.LongitudeLimits);

                %guardamos esta referencia en el mapa
                rrMap.GeoReference = [lat0,lon0];

                %3. Lista temporal para guardar los carriles validos
                misCarriles = roadrunner.hdmap.Lane.empty;
                misBoundaries = roadrunner.hdmap.LaneBoundary.empty;

                %3. Bucle procesar cada calle
                nCalles = length(app.DatosCarreteras);

                %No Pre-asignamos memoria para velocidad (opcional pero recomendado)
                %rrMap.Lanes(nCalles) = roadrunner.hdmap.Lane;

                for i = 1:nCalles
                    %A. Extraer coordenadas crudas (Grados)
                    lat = app.DatosCarreteras(i).Y;
                    lon = app.DatosCarreteras(i).X;

                    %B. Limpiar NaNs (shapered pone NaNs al final de cada linea)
                    idxValidos = ~isnan(lat) & ~isnan(lon);
                    lat = lat(idxValidos);
                    lon = lon(idxValidos);

                    if length(lat) < 2, continue; end

                    %C. Obtener elevacion (Z) Interpolacion
                    z= geointerp(app.ElevacionZ, app.ElevacionRef, lat, lon, 'Cubic');

                    % Correcciondeteccion de puntos fuera del mapa de
                    % elevacion
                    puntosValidos = ~isnan(z);

                    %Si toda la carretera esta afuera, la saltamos
                    if sum(puntosValidos) < 2
                        continue;
                    end

                    %Recostamos: Nos quedamos SOLO con la parte que tiene
                    %altura valida
                    lat = lat(puntosValidos);
                    lon = lon(puntosValidos);
                    z = z(puntosValidos);

                    %D. Conversion de coordenadas (Grados -> metros locales)
                    %Usamos una aproximacion local simple (Flat Earth)
                    %respecto al origen
                    [x_local, y_local] = app.gradosAMetros(lat,lon,lat0,lon0);

                    % Suavizar coordenadas para reducir ruido y evitar que carriles paralelos se crucen
                    if length(x_local) > 4
                        x_local = smoothdata(x_local, 'gaussian', 5);
                        y_local = smoothdata(y_local, 'gaussian', 5);
                    end
                    
                    % Normalizar vectores y calcular normales para los limites
                    x_local = x_local(:);
                    y_local = y_local(:);
                    z = z(:);
                    
                    if length(x_local) < 2, continue; end
                    
                    dx = diff(x_local);
                    dy = diff(y_local);
                    len = sqrt(dx.^2 + dy.^2);
                    len(len==0) = eps;
                    
                    % Vector Normal (perpendicular a la izquierda)
                    nx = -dy ./ len;
                    ny = dx ./ len;
                    
                    % Extender para igualar longitud de puntos
                    nx_completo = [nx; nx(end)];
                    ny_completo = [ny; ny(end)];
                    
                    laneWidth = 3.5;       % Ancho del carril
                    halfWidth = laneWidth / 2;

                    %E. Crear geometria 3D (Simetrica respecto al eje)
                    % Asumimos que el shapefile es el eje central (Centerline)
                    
                    % Carril Derecho (Forward) -> Desplazado a la derecha (-Normal)
                    x_R = x_local - nx_completo * halfWidth;
                    y_R = y_local - ny_completo * halfWidth;
                    geo_R = [x_R, y_R, z];

                    % Carril Izquierdo (Backward) -> Desplazado a la izquierda (+Normal)
                    x_L = x_local + nx_completo * halfWidth;
                    y_L = y_local + ny_completo * halfWidth;
                    geo_L = [x_L, y_L, z];

                    % 1. Carril Derecho (Forward)
                    laneR = roadrunner.hdmap.Lane();
                    laneR.ID = "Road_" + string(i) + "_R";
                    laneR.Geometry = geo_R;
                    laneR.LaneType = "Driving";
                    laneR.TravelDirection = "Forward";
                    
                    % 2. Carril Izquierdo (Backward)
                    laneL = roadrunner.hdmap.Lane();
                    laneL.ID = "Road_" + string(i) + "_L";
                    laneL.Geometry = geo_L;
                    laneL.LaneType = "Driving";
                    laneL.TravelDirection = "Backward";

                    % --- CREAR LIMITES (Boundaries) ---
                    
                    % Limite Central (Eje de la carretera)
                    bndCenter = roadrunner.hdmap.LaneBoundary();
                    bndCenter.ID = "Bnd_C_" + string(i);
                    bndCenter.Geometry = [x_local, y_local, z]; 
                    
                    % Limite Derecho (Externo)
                    x_Re = x_local - nx_completo * laneWidth;
                    y_Re = y_local - ny_completo * laneWidth;
                    bndRight = roadrunner.hdmap.LaneBoundary();
                    bndRight.ID = "Bnd_R_" + string(i);
                    bndRight.Geometry = [x_Re, y_Re, z];

                        
                    % Limite Izquierdo (Externo)
                    x_Le = x_local + nx_completo * laneWidth;
                    y_Le = y_local + ny_completo * laneWidth;
                    bndLeft = roadrunner.hdmap.LaneBoundary();
                    bndLeft.ID = "Bnd_L_" + string(i);
                    bndLeft.Geometry = [x_Le, y_Le, z];

                    % --- VINCULACION ---
                    
                    % Lane R (Derecha): Izquierda=Central, Derecha=Externo
                    leftBoundary(laneR, bndCenter.ID, Alignment="Forward");
                    rightBoundary(laneR, bndRight.ID, Alignment="Forward");

                    % Lane L (Izquierda): Derecha=Central, Izquierda=Externo
                    rightBoundary(laneL, bndCenter.ID, Alignment="Forward");
                    leftBoundary(laneL, bndLeft.ID, Alignment="Forward");

                    % Guardar en listas
                    misCarriles(end+1) = laneR;  %#ok<AGROW>
                    misCarriles(end+1) = laneL;  %#ok<AGROW>
                    
                    misBoundaries(end+1) = bndCenter; %#ok<AGROW>
                    misBoundaries(end+1) = bndRight;  %#ok<AGROW>
                    misBoundaries(end+1) = bndLeft;   %#ok<AGROW>
                end

                %Asignar todos los carriles al mapa de golpe
                rrMap.Lanes = misCarriles;
                rrMap.LaneBoundaries = misBoundaries; 
                
                %Abrir ventana de "Guardar como"
                %Filtros: .rrhd, Titulo de ventana, Nombre por defecto
                [file, path] = uiputfile('*.rrhd', 'Guardar Mapa RoadRunner','MapaGenerado.rrhd');

                %Verificar si el usuario presiono "Cancelar"
                if isequal(file,0) || isequal(path,0)
                    %Restauramos el cursor y salimos sin hacer nada
                    set(app.UIFigure, 'Pointer', 'arrow');
                    return;
                end

                %Construir la ruta completa (Carpeta + Nombre de archivo)
                outputFullpath = fullfile(path,file);

                
                %Escribir el archivo en la ruta elegida
                write(rrMap, outputFullpath);
                uialert(app.UIFigure, ['Mapa guardado exitosamente en:' outputFullpath], 'Exito');

            catch ME
                uialert(app.UIFigure, ['Error exportando: ' ME.message], 'Error');
            end

            set(app.UIFigure, 'Pointer', 'arrow');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CargarArchivoElevacionButton = uibutton(app.UIFigure, 'push');
            app.CargarArchivoElevacionButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonCargarMapa, true);
            app.CargarArchivoElevacionButton.Text = 'CargarArchivo Elevacion';
            app.VerTerrenoCheckBox = uicheckbox(app.UIFigure);
            app.VerTerrenoCheckBox.ValueChangedFcn = createCallbackFcn(app, @VerTerrenoCheckBoxValueChanged, true);
            app.VerTerrenoCheckBox.Text = 'Ver Terreno';
            app.VerCarreterasCheckBox = uicheckbox(app.UIFigure);
            app.VerCarreterasCheckBox.ValueChangedFcn = createCallbackFcn(app, @VerCarreterasCheckBoxValueChanged, true);
            app.VerCarreterasCheckBox.Text = 'Ver Carreteras';
            app.MapperLabel = uilabel(app.UIFigure);
            app.MapperLabel.Text = 'Mapper';
            app.CargarArchivoCarreterasButton = uibutton(app.UIFigure, 'push');
            app.CargarArchivoCarreterasButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonCargarCarreteras, true);
            app.CargarArchivoCarreterasButton.Text = 'Cargar Archivo Carreteras';
            app.ExportarRoadButton = uibutton(app.UIFigure, 'push');
            app.ExportarRoadButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonExportarRoadRunner, true);
            app.ExportarRoadButton.Text = 'Exportar Road ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mapperV3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end